classdef Ilayda_Topuz_EE101_TermProject1 < matlab.apps.AppBase
    %ILAYDA_TOPUZ_EE101_TERMPROJECT1 app

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button_21           matlab.ui.control.Button
        EditField           matlab.ui.control.EditField
        Label_4             matlab.ui.control.Label
        Button_20           matlab.ui.control.Button
        Button_18           matlab.ui.control.Button
        Button_19           matlab.ui.control.Button
        EditField_Result    matlab.ui.control.EditField
        Label_3             matlab.ui.control.Label
        Button_3            matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        xButton             matlab.ui.control.Button
        Button              matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Button_13           matlab.ui.control.Button
        Button_7            matlab.ui.control.Button
        Button_8            matlab.ui.control.Button
        Button_14           matlab.ui.control.Button
        Button_15           matlab.ui.control.Button
        Button_10           matlab.ui.control.Button
        Button_11           matlab.ui.control.Button
        Button_12           matlab.ui.control.Button
        logButton           matlab.ui.control.Button
        EXPButton           matlab.ui.control.Button
        tanButton           matlab.ui.control.Button
        cosButton           matlab.ui.control.Button
        sinButton           matlab.ui.control.Button
        CEButton            matlab.ui.control.Button
        DeveloperEditField  matlab.ui.control.EditField
        Label_2             matlab.ui.control.Label
    end

    
    properties (Access = private)
     equalButtonPressed=0;
     pressedOperation=0;
     pressedMinus=0;
     pressedNumber=0
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CEButton
        function PressCE(app, event)
            app.EditField.Value = "";
            app.EditField_Result.Value = "Ans=0";
              app.equalButtonPressed=0;
                   app.pressedOperation=0;
                   app.pressedMinus=0;
                   app.pressedNumber=0;
        end

        % Button pushed function: Button_10
        function Press1(app, event)
               if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="1";
                app.equalButtonPressed=0;
                   app.pressedOperation=0;
                   app.pressedMinus=0;
                   
            else 
            app.EditField.Value = strcat(app.EditField.Value, "1");
               end
                 app.pressedOperation=0;
               app.pressedNumber=1;
        end

        % Button pushed function: Button_13
        function Press2(app, event)
               if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="2";
                app.equalButtonPressed=0;
                   app.pressedOperation=0;
                   app.pressedMinus=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, "2");
               end
                 app.pressedOperation=0;
               app.pressedNumber=1;
        end

        % Button pushed function: Button_5
        function Press3(app, event)
            if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="3";
                app.equalButtonPressed=0;
                  
                   app.pressedMinus=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, "3");
            end
             app.pressedOperation=0;
            app.pressedNumber=1;
        end

        % Button pushed function: Button_11
        function Press4(app, event)
        if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="4";
                app.equalButtonPressed=0;
                   app.pressedOperation=0;
                   app.pressedMinus=0;
       else 
            app.EditField.Value = strcat(app.EditField.Value, "4");
        end
          app.pressedOperation=0;
        app.pressedNumber=1;
        end

        % Button pushed function: Button_14
        function Press5(app, event)
               if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="5";
                app.equalButtonPressed=0;
                   app.pressedOperation=0;
                   app.pressedMinus=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, "5");
               end
                 app.pressedOperation=0;
               app.pressedNumber=1;
        end

        % Button pushed function: Button_6
        function Press6(app, event)
          if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="6";
                app.equalButtonPressed=0;
                 app.pressedOperation=0;
                 app.pressedMinus=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, "6");
          end
            app.pressedOperation=0;
          app.pressedNumber=1;
        end

        % Button pushed function: Button_12
        function Press7(app, event)
                if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="7";
                app.equalButtonPressed=0;
                   app.pressedOperation=0;
                   app.pressedMinus=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, "7");
                end
                  app.pressedOperation=0;
                app.pressedNumber=1;
        end

        % Button pushed function: Button_15
        function Press8(app, event)
                if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="8";
                app.equalButtonPressed=0;
                   app.pressedOperation=0;
                   app.pressedMinus=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, "8");
                end
                  app.pressedOperation=0;
                app.pressedNumber=1;
        end

        % Button pushed function: Button_7
        function Press9(app, event)
           if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="9";
                app.equalButtonPressed=0;
                   app.pressedOperation=0;
                   app.pressedMinus=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, "9");
           end
             app.pressedOperation=0;
           app.pressedNumber=1;
        end

        % Button pushed function: Button_18
        function Press0(app, event)
            if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="0";
                app.equalButtonPressed=0;
                   app.pressedOperation=0;
                   app.pressedMinus=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, "0");
            end
              app.pressedOperation=0;
            app.pressedNumber=1;
        end

        % Button pushed function: Button_4
        function PressEqual(app, event)
           app.equalButtonPressed=1;
            app.pressedOperation=0;
                   app.pressedMinus=0;
                   app.pressedNumber=0;
            app.EditField_Result.Value = strcat(app.EditField.Value , "=");
        app.EditField.Value = num2str(eval(app.EditField.Value));
        end

        % Button pushed function: xButton
        function PressX(app, event)
        if app.pressedOperation==0
            app.EditField.Value=strcat( app.EditField.Value," *");
            app.pressedOperation=1;
            app.equalButtonPressed=0;
        end
app.pressedNumber=0;
        
        end

        % Button pushed function: Button
        function PressDivide(app, event)
        if app.pressedOperation==0
            app.EditField.Value=strcat( app.EditField.Value,"/");
            app.pressedOperation=1;
              app.equalButtonPressed=0;
        end
        app.pressedNumber=0;
        end

        % Button pushed function: Button_2
        function PressSubstraction(app, event)
         if app.pressedOperation==0

            app.EditField.Value=strcat( app.EditField.Value,"-");
            app.pressedOperation=1;
         elseif app.pressedMinus==0
             app.EditField.Value=strcat( app.EditField.Value,"-");
             app.pressedMinus=1;
              app.pressedOperation=1;
         end
           app.equalButtonPressed=0;
           app.pressedNumber=0;
        end

        % Button pushed function: Button_3
        function PressAddition(app, event)
         if app.pressedOperation==0
            app.EditField.Value=strcat( app.EditField.Value,"+");
            app.pressedOperation=1;
              app.equalButtonPressed=0;
         end
         app.pressedNumber=0;
        end

        % Button pushed function: sinButton
        function PressSin(app, event)
        if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="sind(";
                app.equalButtonPressed=0;
        else 
            app.EditField.Value = strcat(app.EditField.Value, "sind(");
        end   
        app.pressedNumber=0;
        end

        % Button pushed function: Button_19
        function PressRightParenthesis(app, event)
            if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value=")";
                app.equalButtonPressed=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, ")");
            end   
            app.pressedNumber=0;
        end

        % Button pushed function: Button_20
        function PressLeftParenthesis(app, event)
            if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="(";
                app.equalButtonPressed=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, "(");
            end      
            app.pressedNumber=0;
        end

        % Button pushed function: cosButton
        function PressCos(app, event)
            if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="cosd(";
                app.equalButtonPressed=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, "cosd(");
            end    
            app.pressedNumber=0;
        end

        % Button pushed function: tanButton
        function PressTan(app, event)
            if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="tand(";
                app.equalButtonPressed=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, "tand(");
            end    
            app.pressedNumber=0;
        end

        % Button pushed function: logButton
        function PressLog(app, event)
            if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="log10(";
                app.equalButtonPressed=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, "log10(");
            end
            app.pressedNumber=0;
        end

        % Button pushed function: EXPButton
        function PressEXP(app, event)
       if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="exp(";
                app.equalButtonPressed=0;
        else 
            app.EditField.Value = strcat(app.EditField.Value, "exp(");
       end    
       app.pressedNumber=0;
        end

        % Button pushed function: Button_21
        function PressPi(app, event)
          if app.pressedNumber==0

              if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value="pi";
                app.equalButtonPressed=0;

            else 
            app.EditField.Value = strcat(app.EditField.Value, "pi");
              end   
              app.pressedNumber=1;
                app.pressedOperation=0;
          end

        end

        % Button pushed function: Button_8
        function PressDat(app, event)
            if app.equalButtonPressed==1
                app.EditField_Result.Value = strcat("Ans=",app.EditField.Value);
                app.EditField.Value=".";
                app.equalButtonPressed=0;
            else 
            app.EditField.Value = strcat(app.EditField.Value, ".");
            end       
             app.pressedNumber=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Developer:';
            app.DeveloperEditField = uieditfield(app.UIFigure, 'text');
            app.DeveloperEditField.Value = 'İlayda T';
            app.CEButton = uibutton(app.UIFigure, 'push');
            app.CEButton.ButtonPushedFcn = createCallbackFcn(app, @PressCE, true);
            app.CEButton.Text = 'CE';
            app.sinButton = uibutton(app.UIFigure, 'push');
            app.sinButton.ButtonPushedFcn = createCallbackFcn(app, @PressSin, true);
            app.sinButton.Text = 'sin';
            app.cosButton = uibutton(app.UIFigure, 'push');
            app.cosButton.ButtonPushedFcn = createCallbackFcn(app, @PressCos, true);
            app.cosButton.Text = 'cos';
            app.tanButton = uibutton(app.UIFigure, 'push');
            app.tanButton.ButtonPushedFcn = createCallbackFcn(app, @PressTan, true);
            app.tanButton.Text = 'tan';
            app.EXPButton = uibutton(app.UIFigure, 'push');
            app.EXPButton.ButtonPushedFcn = createCallbackFcn(app, @PressEXP, true);
            app.EXPButton.Text = 'EXP';
            app.logButton = uibutton(app.UIFigure, 'push');
            app.logButton.ButtonPushedFcn = createCallbackFcn(app, @PressLog, true);
            app.logButton.Text = 'log';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Press7, true);
            app.Button_12.Text = '7';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Press4, true);
            app.Button_11.Text = '4';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Press1, true);
            app.Button_10.Text = '1';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Press8, true);
            app.Button_15.Text = '8';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Press5, true);
            app.Button_14.Text = '5';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @PressDat, true);
            app.Button_8.Text = '.';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Press9, true);
            app.Button_7.Text = '9';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Press2, true);
            app.Button_13.Text = '2';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Press6, true);
            app.Button_6.Text = '6';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Press3, true);
            app.Button_5.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @PressEqual, true);
            app.Button_4.Text = '=';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @PressDivide, true);
            app.Button.Text = '÷';
            app.xButton = uibutton(app.UIFigure, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @PressX, true);
            app.xButton.Text = 'x';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @PressSubstraction, true);
            app.Button_2.Text = '-';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @PressAddition, true);
            app.Button_3.Text = '+';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.EditField_Result = uieditfield(app.UIFigure, 'text');
            app.EditField_Result.Value = '                                                                                                                     ';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @PressRightParenthesis, true);
            app.Button_19.Text = ')';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Press0, true);
            app.Button_18.Text = '0';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @PressLeftParenthesis, true);
            app.Button_20.Text = '(';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '                                                                                                                     ';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @PressPi, true);
            app.Button_21.Text = {'π'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ilayda_Topuz_EE101_TermProject1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end